classdef Analyze_smLD_Handy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        TrajectorymatrixEditFieldLabel  matlab.ui.control.Label
        TrajectorymatrixEditField       matlab.ui.control.EditField
        LoadTrajectoriesButton          matlab.ui.control.Button
        DiscardTrajectoryButton         matlab.ui.control.Button
        IdentifyChangePointsButton      matlab.ui.control.Button
        SaveandProceedButton            matlab.ui.control.Button
        LocationSliderLabel             matlab.ui.control.Label
        LocationSlider                  matlab.ui.control.Slider
        SelectedChangePointDropDownLabel  matlab.ui.control.Label
        SelectedChangePointDropDown     matlab.ui.control.DropDown
    end


    properties (Access = private)
        TrajectoryMatrix    % stores a matrix of intensity traces
        TrajectoryIndex     % identifies the current trajectory
        TrajectoryLength    % length of each trajectory, in frames
        Limit               % last trajectory
        ExposureTime        % exposure time of camera (s)
        ChangePoints        % frame index of change points for current trace
        % look I'd program this better but I'm not doing queues in MATLAB
    end

    methods (Access = private)
    
        function results = PlotCurrentTrace(app)
            l = app.TrajectoryLength;
            xs = linspace(1, l, l);
            ys = app.TrajectoryMatrix(app.TrajectoryIndex,:);
            plot(app.UIAxes, xs, ys);
           
        end
        
        function results = PlotChangePoints(app)
            idxs = linspace(1, length(app.ChangePoints), length(app.ChangePoints));
            idxs_str = split(num2str(idxs));
            y=get(app.UIAxes,'ylim');
            hold(app.UIAxes, 'on');
            for i = 1:length(idxs)
                plot(app.UIAxes, [app.ChangePoints(i), app.ChangePoints(i)],y,'r');
                text(app.UIAxes, app.ChangePoints(i) - 20, 0, idxs_str(i));
            end
            hold(app.UIAxes, 'off');
            
            
        end
        
        function results = RedrawPlot(app)
            % clear current axes and plot data again
            cla(app.UIAxes);
            PlotCurrentTrace(app);
            PlotChangePoints(app);
        end
        
        function results = UpdateSlider(app, idx)
            % Set slider limits
            lower_limit = 0;
            upper_limit = app.TrajectoryLength;
            if idx > 1
                lower_limit = app.ChangePoints(idx-1);
            end
            if idx < length(app.ChangePoints)
                upper_limit = app.ChangePoints(idx+1);
            end
                
            app.LocationSlider.Limits = [lower_limit upper_limit];
            
            % Set the slider value to the current change point
            app.LocationSlider.Value = app.ChangePoints(idx);
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadTrajectoriesButton
        function LoadTrajectoriesButtonPushed(app, event)
            % load the matrix
            data = load(app.TrajectorymatrixEditField.Value);
            app.TrajectoryMatrix = data.mansel_int_array;
            app.ExposureTime = data.Exposuretime;
            app.TrajectoryIndex = 1;
            app.TrajectoryLength = length(data.mansel_int_array(1,:));
            app.Limit = length(data.mansel_int_array(:,1));
            
            % display the first trajectory
            PlotCurrentTrace(app);
            
          
        end

        % Value changed function: TrajectorymatrixEditField
        function TrajectorymatrixEditFieldValueChanged(app, event)
            value = app.TrajectorymatrixEditField.Value;
            
        end

        % Button pushed function: DiscardTrajectoryButton
        function DiscardTrajectoryButtonPushed(app, event)
            app.TrajectoryIndex = app.TrajectoryIndex + 1;
            if app.TrajectoryIndex > app.Limit
               delete(app); 
            else
                PlotCurrentTrace(app);
            end
        end

        % Button pushed function: IdentifyChangePointsButton
        function IdentifyChangePointsButtonPushed(app, event)
            change_points = findcp(app.TrajectoryMatrix(app.TrajectoryIndex,:));
            app.ChangePoints = change_points;
            
            % Display change points on the current plot
            PlotChangePoints(app);
            
            % Also update the dropdown and slider
            idxs = linspace(1, length(app.ChangePoints), length(app.ChangePoints));
            idxs_str = split(num2str(idxs));
            app.SelectedChangePointDropDown.Items = idxs_str;
            app.SelectedChangePointDropDown.Value = '1';
            UpdateSlider(app, 1);
        end

        % Value changing function: LocationSlider
        function LocationSliderValueChanging(app, event)
            changingValue = event.Value;
            cp_index_str = app.SelectedChangePointDropDown.Value;
            cp_index = str2num(cp_index_str);
            app.ChangePoints(cp_index) = changingValue;
            RedrawPlot(app);
        end

        % Value changed function: SelectedChangePointDropDown
        function SelectedChangePointDropDownValueChanged(app, event)
            value = app.SelectedChangePointDropDown.Value;
            
            % Set the slider limit to the values of the surrounding
            % change points, or the end of the trajectory
            x = str2num(value);
            UpdateSlider(app, x);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Intensity Trace')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'Fluorescence Intensity (a.u.)')
            app.TrajectorymatrixEditFieldLabel = uilabel(app.UIFigure);
            app.TrajectorymatrixEditFieldLabel.Text = 'Trajectory matrix';
            app.TrajectorymatrixEditField = uieditfield(app.UIFigure, 'text');
            app.TrajectorymatrixEditField.ValueChangedFcn = createCallbackFcn(app, @TrajectorymatrixEditFieldValueChanged, true);
            app.LoadTrajectoriesButton = uibutton(app.UIFigure, 'push');
            app.LoadTrajectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrajectoriesButtonPushed, true);
            app.LoadTrajectoriesButton.Text = 'Load Trajectories';
            app.DiscardTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.DiscardTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardTrajectoryButtonPushed, true);
            app.DiscardTrajectoryButton.Text = 'Discard Trajectory';
            app.IdentifyChangePointsButton = uibutton(app.UIFigure, 'push');
            app.IdentifyChangePointsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyChangePointsButtonPushed, true);
            app.IdentifyChangePointsButton.Text = 'Identify Change Points';
            app.SaveandProceedButton = uibutton(app.UIFigure, 'push');
            app.SaveandProceedButton.Text = 'Save and Proceed';
            app.LocationSliderLabel = uilabel(app.UIFigure);
            app.LocationSliderLabel.Text = 'Location';
            app.LocationSlider = uislider(app.UIFigure);
            app.LocationSlider.ValueChangingFcn = createCallbackFcn(app, @LocationSliderValueChanging, true);
            app.SelectedChangePointDropDownLabel = uilabel(app.UIFigure);
            app.SelectedChangePointDropDownLabel.Text = 'Selected Change Point';
            app.SelectedChangePointDropDown = uidropdown(app.UIFigure);
            app.SelectedChangePointDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedChangePointDropDownValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Analyze_smLD_Handy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end